classdef SeeedSensor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        PortsDropDownLabel   matlab.ui.control.Label
        PortsDropDown        matlab.ui.control.DropDown
        ConnectButton        matlab.ui.control.Button
        Button               matlab.ui.control.Button
        DisconnectButton     matlab.ui.control.Button
        DebugTextArea        matlab.ui.control.TextArea
        UserrangeLabel       matlab.ui.control.Label
        UserpresenceLabel    matlab.ui.control.Label
        UsermoveLabel        matlab.ui.control.Label
        DataLampLabel        matlab.ui.control.Label
        DataLamp             matlab.ui.control.Lamp
        DistanceatrestLabel  matlab.ui.control.Label
        AngleatrestLabel     matlab.ui.control.Label
        BodymovementLabel    matlab.ui.control.Label
        CenterPanel          matlab.ui.container.Panel
        LabelHR              matlab.ui.control.Label
        LabelRR              matlab.ui.control.Label
        AxesHR               matlab.ui.control.UIAxes
        AxesRR               matlab.ui.control.UIAxes
        RightPanel           matlab.ui.container.Panel
        AxesHRWave           matlab.ui.control.UIAxes
        AxesRRWave           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % see: https://github.com/limengdu/Seeed_60GHz_BreathingHeartRadar/blob/main/60ghzbreathheart.cpp
    % see: https://github.com/limengdu/Seeed_60GHz_BreathingHeartRadar/blob/main/60ghzbreathheart.h
    properties (Access = public)
        MyTimer = 0; % update views
        s = NaN; % serial port
        Msg = [];
        dataLen = 0;
        HRarr = NaN(50,1);
        RRarr = NaN(50,1);
        currentHR = 0;
        currentRR = 0;
        
        % DEFINES
        POS_CONTROL = 0x03;
        POS_COMMAND = 0x04;
        POS_LEN_START = 0x05;
        POS_LEN_END = 0x06;
        POS_DATA_START = 0x07;
        
        PRESENCE_INF = 0x01;       %Presence Information
        SOMEONE_HERE = 0x01;       %Someone here
        NOONE_HERE = 0x00;         %Noone here
        
        MOVE_INF = 0x02;           %Campaign Information
        NONE = 0x00;               %None
        CA_CLOSE = 0x01;           %Someone approaches
        CA_AWAY = 0x02;            %Some people stay away
        DISORDER = 0x03;           %Disorderly movement
        
        BODY_SIG = 0x03;           %Body movement information
        
        HUMAN_PSE_RADAR = 0x80;    %Human presence data
        HEART_RATE_RADAR = 0x81;   %Respiratory heart rate data
        
        RATE_DATA = 0x01;          %Heartbeat Pack
        RATE_NORMAL = 0x01;        %Normal heart rate
        RATE_RAPID = 0x02;         %Rapid heart rate
        RATE_SLOW = 0x03;          %Slow heart rate
        
        HEART_RATE = 0x02;         %Heart rate
        HEART_RATE_WAVE = 0x03;    %Heart rate waveform (No analysis for now)
        
        BREATH_DATA = 0x04;        %Breathing data
        BREATH_NORMAL = 0x01;      %Normal breathing
        BREATH_RAPID = 0x02;       %Acute respiratory abnormalities
        BREATH_SLOW = 0x03;        %Slow heartbeat
        
        BREATH_VAL = 0x05;         %Breathing values
        BREATH_WAVE = 0x06;        %Respiratory waveform (No analysis for now)
        
        LOCA_DET_ANOMAL = 0x07;    %Location detection anomaly
        OUT_OF_RANGE = 0x00;       %Detection of targets out of range
        WITHIN_RANGE = 0x01;       %Within the scope
        
        DISTANCE = 0x08;           %Distance at rest
        ANGLE = 0x09;              %Angle at rest
        
        startMarker = 0x53         %83, Message head
        endMarker = 0x54;          %84, Message tail
    end
    
    methods (Access = public)
        
        function updateSerialPorts(app)
            ports = serialportlist("available");
            app.PortsDropDown.Items = ports(contains(ports,'cu') & ~contains(ports,'Bluetooth'));
        end
        
        function TimerCallback(app, ~, ~)  % object, event
            app.Msg = [];
            recvInProgress = 0;
            ndx = 0;
            newData = 0;
            app.DataLamp.Color = [0 1 0];
            while(newData == 0)
                rb = read(app.s,1,"uint8");
                if recvInProgress
                    if rb ~= app.endMarker
                        ndx = ndx + 1;
                        app.Msg(ndx) = rb;
                    else
                        recvInProgress = 0;
                        app.dataLen = ndx;
                        ndx = 0;
                        newData = 1;
                    end
                elseif rb == app.startMarker
                    recvInProgress = 1;
                end
            end
            decodeMsg(app);
            app.DataLamp.Color = [0 0 0];
        end
        
        function toDebug(app,str)
            app.DebugTextArea.Value = sprintf('%s',str);
        end
        
        function decodeMsg(app)
            % add headers back in for full payload
            dataMsg = zeros(app.dataLen+3,1);
            dataMsg(1) = 0x53;
            for n = 1:app.dataLen
                dataMsg(n+1) = app.Msg(n);
            end
            dataMsg(app.dataLen+2) = 0x54;
            dataMsg(app.dataLen+3) = 0x43;
            
            toDebug(app,sprintf('0x%02x ',dataMsg));
            
            switch dataMsg(app.POS_CONTROL)
                case app.HUMAN_PSE_RADAR
                    switch dataMsg(app.POS_COMMAND)
                        case app.PRESENCE_INF
                            switch dataMsg(app.POS_DATA_START)
                                case app.NOONE_HERE
                                    app.UserpresenceLabel.Text = "No one is detected.";
                                case app.SOMEONE_HERE
                                    app.UserpresenceLabel.Text = "Someone is detected.";
                            end
                        case app.MOVE_INF
                            switch dataMsg(app.POS_DATA_START)
                                case app.NONE
                                    app.UsermoveLabel.Text = "No movement.";
                                case app.CA_CLOSE
                                    app.UsermoveLabel.Text = "User is close.";
                                case app.CA_AWAY
                                    app.UsermoveLabel.Text = "User is away.";
                                case app.DISORDER
                                    app.UsermoveLabel.Text = "Disordered movement.";
                            end
                        case app.BODY_SIG
                            app.BodymovementLabel.Text = sprintf("Body movement: %i",dataMsg(app.POS_DATA_START));
                    end
                case app.HEART_RATE_RADAR
                    switch dataMsg(app.POS_COMMAND)
                        case app.HEART_RATE
                            app.currentHR = dataMsg(app.POS_DATA_START);
                            app.HRarr(1:end-1) = app.HRarr(2:end);
                            app.HRarr(end) = app.currentHR;
                            plot(app.AxesHR,app.HRarr,'k-','linewidth',2);
                            hold(app.AxesHR,'on');
                            plot(app.AxesHR,numel(app.HRarr),app.currentHR,'r.','markersize',20);
                            hold(app.AxesHR,'off');
                            app.LabelHR.Text = num2str(app.currentHR);
                        case app.HEART_RATE_WAVE
                            waveArr = dataMsg(app.POS_DATA_START:app.POS_DATA_START+dataMsg(app.POS_LEN_END)-1);
                            plot(app.AxesHRWave,waveArr,'k-','linewidth',2);
                            xlim(app.AxesHRWave,[1,numel(waveArr)]);
                        case app.BREATH_VAL
                            app.currentRR = dataMsg(app.POS_DATA_START);
                            app.RRarr(1:end-1) = app.RRarr(2:end);
                            app.RRarr(end) = app.currentRR;
                            plot(app.AxesRR,app.RRarr,'k-','linewidth',2);
                            hold(app.AxesRR,'on');
                            plot(app.AxesRR,numel(app.RRarr),app.currentRR,'r.','markersize',20);
                            hold(app.AxesRR,'off');
                            app.LabelRR.Text = num2str(app.currentRR);
                        case app.BREATH_WAVE
                            waveArr = dataMsg(app.POS_DATA_START:app.POS_DATA_START+dataMsg(app.POS_LEN_END)-1);
                            plot(app.AxesRRWave,waveArr,'k-','linewidth',2);
                            xlim(app.AxesRRWave,[1,numel(waveArr)]);
                        case app.LOCA_DET_ANOMAL
                            switch dataMsg(app.POS_DATA_START)
                                case app.OUT_OF_RANGE
                                    app.UserrangeLabel.Text = "User out of range.";
                                case app.WITHIN_RANGE
                                    app.UserrangeLabel.Text = "User within range.";
                            end
                        case app.DISTANCE
                            twoByteVal = bitshift(dataMsg(app.POS_DATA_START),8) + dataMsg(app.POS_DATA_START+1);
                            app.DistanceatrestLabel.Text = sprintf("Distance: %i",twoByteVal);
                        case app.ANGLE
                            twoByteVal = bitshift(dataMsg(app.POS_DATA_START),8) + dataMsg(app.POS_DATA_START+1);
                            app.AngleatrestLabel.Text = sprintf("Angle: %i",twoByteVal);
                    end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MyTimer = timer('Period',0.01,'ExecutionMode','fixedSpacing',...
                'TasksToExecute',Inf);
            app.MyTimer.TimerFcn = @app.TimerCallback; % Your Timer Callback
            updateSerialPorts(app);
            plot(app.AxesHR,app.HRarr);
            xlim(app.AxesHR,[1,numel(app.HRarr)]);
            plot(app.AxesRR,app.RRarr);
            xlim(app.AxesRR,[1,numel(app.RRarr)]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {372, 372, 372};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {372, 372};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {207, '1x', 215};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            updateSerialPorts(app);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                app.s = serialport(app.PortsDropDown.Value,115200);
                app.DisconnectButton.Enable = 1;
                app.ConnectButton.Enable = 0;
                start(app.MyTimer);
            catch
                % did not work
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            stop(app.MyTimer);
            app.DisconnectButton.Enable = 0;
            app.ConnectButton.Enable = 1;
            app.s = NaN;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            listOfTimers = timerfindall;
            if ~isempty(listOfTimers)
                delete(listOfTimers(:));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PortsDropDownLabel = uilabel(app.LeftPanel);
            app.PortsDropDownLabel.Text = 'Ports';
            app.PortsDropDown = uidropdown(app.LeftPanel);
            app.PortsDropDown.Items = {'None'};
            app.PortsDropDown.Value = 'None';
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '⟳';
            app.DisconnectButton = uibutton(app.LeftPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.DebugTextArea = uitextarea(app.LeftPanel);
            app.UserrangeLabel = uilabel(app.LeftPanel);
            app.UserrangeLabel.Text = '+User range.';
            app.UserpresenceLabel = uilabel(app.LeftPanel);
            app.UserpresenceLabel.Text = '+User presence.';
            app.UsermoveLabel = uilabel(app.LeftPanel);
            app.UsermoveLabel.Text = '+User move.';
            app.DataLampLabel = uilabel(app.LeftPanel);
            app.DataLampLabel.Text = 'Data';
            app.DataLamp = uilamp(app.LeftPanel);
            app.DistanceatrestLabel = uilabel(app.LeftPanel);
            app.DistanceatrestLabel.Text = '+Distance at rest.';
            app.AngleatrestLabel = uilabel(app.LeftPanel);
            app.AngleatrestLabel.Text = '+Angle at rest.';
            app.BodymovementLabel = uilabel(app.LeftPanel);
            app.BodymovementLabel.Text = '+Body movement.';
            app.CenterPanel = uipanel(app.GridLayout);
            app.LabelHR = uilabel(app.CenterPanel);
            app.LabelHR.Text = '00';
            app.LabelRR = uilabel(app.CenterPanel);
            app.LabelRR.Text = '00';
            app.AxesHR = uiaxes(app.CenterPanel);
            title(app.AxesHR, 'Heart Rate')
            xlabel(app.AxesHR, 'Samples')
            ylabel(app.AxesHR, 'HR (BPM)')
            app.AxesRR = uiaxes(app.CenterPanel);
            title(app.AxesRR, 'Respiratory Rate')
            xlabel(app.AxesRR, 'Samples')
            ylabel(app.AxesRR, 'RR (BPM)')
            app.RightPanel = uipanel(app.GridLayout);
            app.AxesHRWave = uiaxes(app.RightPanel);
            title(app.AxesHRWave, 'Heart Wave')
            xlabel(app.AxesHRWave, 'Samples')
            ylabel(app.AxesHRWave, 'Wave')
            app.AxesRRWave = uiaxes(app.RightPanel);
            title(app.AxesRRWave, 'Respiratory Wave')
            xlabel(app.AxesRRWave, 'Samples')
            ylabel(app.AxesRRWave, 'Wave')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SeeedSensor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end